classdef servo_control_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DimmerAppUIFigure            matlab.ui.Figure
        SetButton                    matlab.ui.control.Button
        degsLabel                    matlab.ui.control.Label
        SpeedEditField               matlab.ui.control.EditField
        SpeedEditFieldLabel          matlab.ui.control.Label
        PortDropDown                 matlab.ui.control.DropDown
        PortDropDownLabel            matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        ServomotorcontrolpanelLabel  matlab.ui.control.Label
        Position0degKnob             matlab.ui.control.Knob
        Label                        matlab.ui.control.Label
    end

    properties (Access = private)
        SerialPortHandler             internal.Serialport
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortDropDown.Items = serialportlist("available");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.SerialPortHandler)
                app.SerialPortHandler = serialport(app.PortDropDown.Value, 115200);
            end     
        end

        % Value changed function: Position0degKnob
        function Position0degKnobValueChanged(app, event)
            value = app.Position0degKnob.Value;
            app.Label.Text = sprintf("Position: %3d deg ", round(value)); 
            if ~isempty(app.SerialPortHandler)
                ctrlMsg = sprintf("p%03d", round(value));
                write(app.SerialPortHandler, ctrlMsg, "char"); 
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if ~isempty(app.SerialPortHandler)
                speed = str2double( app.SpeedEditField.Value );
                ctrlMsg = sprintf("s%03d", round(speed));
                write(app.SerialPortHandler, ctrlMsg, "char"); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DimmerAppUIFigure = uifigure('Visible', 'off');
            app.DimmerAppUIFigure.Name = 'Dimmer App';
            app.Label = uilabel(app.DimmerAppUIFigure);
            app.Label.Text = 'Position: 0 deg';
            app.Position0degKnob = uiknob(app.DimmerAppUIFigure, 'continuous');
            app.Position0degKnob.Limits = [0 180];
            app.Position0degKnob.ValueChangedFcn = createCallbackFcn(app, @Position0degKnobValueChanged, true);
            app.ServomotorcontrolpanelLabel = uilabel(app.DimmerAppUIFigure);
            app.ServomotorcontrolpanelLabel.Text = {'Servo motor'; 'control panel'};
            app.ConnectButton = uibutton(app.DimmerAppUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.DimmerAppUIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.DimmerAppUIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.SpeedEditFieldLabel = uilabel(app.DimmerAppUIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.DimmerAppUIFigure, 'text');
            app.SpeedEditField.Value = '45';
            app.degsLabel = uilabel(app.DimmerAppUIFigure);
            app.degsLabel.Text = 'deg/s';
            app.SetButton = uibutton(app.DimmerAppUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            % Show the figure after all components are created
            app.DimmerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = servo_control_panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DimmerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DimmerAppUIFigure)
        end
    end
end